classdef Aplikacja_Inzynierka < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        TextArea               matlab.ui.control.TextArea
        TextAreaLabel          matlab.ui.control.Label
        BAUDRATEDropDown       matlab.ui.control.DropDown
        BAUDRATEDropDownLabel  matlab.ui.control.Label
        PORTDropDown           matlab.ui.control.DropDown
        PORTDropDownLabel      matlab.ui.control.Label
        ZAPISZButton           matlab.ui.control.Button
        RYSUJButton            matlab.ui.control.Button
        STOPButton             matlab.ui.control.Button
        STARTButton            matlab.ui.control.Button
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        IsRunning               logical = false; % Add IsRunning property
        Data                    double; % To store acquired data
        ConvData                ; % To store converted data
        FolderCounter           double = 1; % Initialize FolderCounter
        SerialObj               ;% To hold the serial object (if needed)
        SerialTimer
    end    
    

    methods (Access = private)
    % Funkcja do aktualizacji listy dostępnych portów COM
    function updateCOMPorts(app)
        comPorts = serialportlist("available"); % Pobranie dostępnych portów COM
        if isempty(comPorts)
            comPorts = {'Brak dostępnych portów'}; % Komunikat, jeśli brak dostępnych portów
        end
        app.PORTDropDown.Items = comPorts; % Ustawienie dostępnych opcji w rozwijanej liście
    end

    % Funkcja do inicjalizacji listy prędkości transmisji
    function initializeBaudRates(app)
        baudRates = {'9600', '19200', '38400', '57600', '115200', '230400', '460800', '921600'}; % Przykładowe prędkości
        app.BAUDRATEDropDown.Items = baudRates; % Ustawienie wartości na rozwijanej liście
    end
    function readSerialData(app)
        if app.IsRunning
            try
                if app.SerialObj.NumBytesAvailable > 0
                    data = readline(app.SerialObj); % Read line from the serial port
                    app.TextArea(data); % Display received data
                    app.TextArea(sprintf('Odczyt portu udany.'));

                end
            catch ME
                app.TextArea(sprintf('Błąd podczas odczytu portu: %s', ME.message));
            end
        end
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
                    % Inicjalizacja list rozwijanych
        updateCOMPorts(app);
        initializeBaudRates(app);

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
         % Czyści TextArea
        app.TextArea.Value = '';  
        % Czyści wszystkie wykresy i mapę
        cla(app.UIAxes);  
        cla(app.UIAxes_2);
        delete(app.Panel.Children);

        app.IsRunning = true;
        % Pobierz wybrany port i prędkość transmisji z rozwijanych list
        port = app.PORTDropDown.Value;
        baudRate = str2double(app.BAUDRATEDropDown.Value);
        
        % Inicjalizacja obiektu portu szeregowego
        app.SerialObj = serialport(port, baudRate, 'Timeout', 1);
        configureTerminator(app.SerialObj, "CR/LF"); % Ustawienie terminatora na CR/LF
        app.TextArea.Value{end+1} = 'Udane połączenie szeregowe.'; % Wyświetlenie komunikatu
        app.TextArea.Value{end+1} ='--------------------------------------------------------------------';

        % Wykonaj funkcje konwersji danych
        app.ConvData = convertedData(app.SerialObj, app); 
        
        % Informacja o zakończeniu zbierania danych

        app.TextArea.Value{end+1} = '--------------------------------------------------------------------';
        app.TextArea.Value{end+1} = 'Wciśnij przycisk "RYSUJ", aby narysować wykresy';

        app.TextArea.Value{end+1} = '--------------------------------------------------------------------';


        end

        % Button pushed function: RYSUJButton
        function RYSUJButtonPushed(app, event)

    % Sprawdź czy są dane akcelerometru
    if isempty(app.ConvData.convAcc)
        app.TextArea.Value{end+1} = 'Brak danych akcelerometru do rysowania wykresu.'; % Komunikat, jeśli brak danych
        return;
    end
    
    % Ryswanie wykresu akcelerometru
    time_acc = app.ConvData.convAcc(:, 1); % Pierwsza kolumna to czas 
    data_acc = app.ConvData.convAcc(:, 2); % Druga kolumna to dane dotyczące przyspieszenia
    plot(app.UIAxes, time_acc, data_acc, '-o');
    title(app.UIAxes, 'Akcelerometr');
    xlabel(app.UIAxes, 'Czas [s]');
    ylabel(app.UIAxes, 'Przyśpieszenie [m/s^2]');
    grid(app.UIAxes, 'on');
    
    app.TextArea.Value{end+1} = 'Rysowanie wykresu akcelerometru zakończone.'; % Informacja o zakończeniu rysowania

    % Sprawdź czy są dane GPS
    if isempty(app.ConvData.convGPS)
        app.TextArea.Value{end+1} = 'Brak danych GPS do rysowania wykresu.'; % Komunikat, jeśli brak danych
        app.TextArea.Value{end+1} ='--------------------------------------------------------------------';
        return;
    end
    
    % Ryswanie wykresu GPS
    time_gps = app.ConvData.convGPS(:, 1); % Pierwsza kolumna to czas 
    speed_gps = app.ConvData.convGPS(:, 2); % Druga kolumna to dane dotyczące prędkości
    plot(app.UIAxes_2, time_gps, speed_gps, '-o');
    title(app.UIAxes_2, 'GPS');
    xlabel(app.UIAxes_2, 'Czas [s]');
    ylabel(app.UIAxes_2, 'Prędkość [km/h]');
    grid(app.UIAxes_2, 'on');
    app.TextArea.Value{end+1} = 'Rysowanie wykresu GPS zakończone.'; % Informacja o zakończeniu rysowania
    
    % Tworzy nową mapę na app.Panel
    gx = geoaxes(app.Panel);
    
    % Pobiera dane współrzędnych
    lat = app.ConvData.convCoordinates(:, 2);
    long = app.ConvData.convCoordinates(:, 3);
    
    % Rysuje trasę jako ciągłą linię z niebieskimi punktami o okrągłym kształcie
    geoplot(gx, lat, long, '-o', 'Color', 'b', ...
        'LineWidth', 2, 'MarkerSize', 6, ...
        'MarkerEdgeColor', 'b', 'MarkerFaceColor', 'b');
    
    % Ustawia mapę na tryb topograficzny
    geobasemap(gx, 'topographic');
    
    % Ustawia automatycznie granice mapy, aby najlepiej dopasować się do trasy
    geolimits(gx, [min(lat)-0.001, max(lat)+0.001], [min(long)-0.001, max(long)+0.001]);
    app.TextArea.Value{end+1} = 'Rysowanie mapy zakończone.'; % Informacja o zakończeniu rysowania
    app.TextArea.Value{end+1} ='--------------------------------------------------------------------';

        end

        % Button pushed function: ZAPISZButton
        function ZAPISZButtonPushed(app, event)
    if isempty(app.ConvData)
        app.TextArea.Value{end+1} = 'Brak danych do zapisania.'; % Komunikat, jeśli brak danych
        return;
    end

    % Pobierz aktualną datę i czas
    currentDateTime = datetime('now', 'Format', 'yyyy-MM-dd_HH-mm-ss');

    % Stwórz folder z nazwą w formacie "Telemetria_yyyy-MM-dd_HH-mm-ss"
    folderName = sprintf('Telemetria_%s', char(currentDateTime));
    mkdir(folderName);

    % Zapisz acceleration data to CSV
    if ~isempty(app.ConvData.convAcc)
        accFileName = fullfile(folderName, 'accData.csv');
        writematrix(app.ConvData.convAcc, accFileName); % Zapisz accData jako .csv
    else
        app.TextArea.Value{end+1} = 'Brak danych akcelerometru do zapisania.'; % Informacja o braku danych
    end

    % Zapisz dane GPS w formacie CSV
    if ~isempty(app.ConvData.convGPS)
        gpsFileName = fullfile(folderName, 'gpsData.csv');
        writematrix(app.ConvData.convGPS, gpsFileName); % Zapisz gpsData jako .csv
    else
        app.TextArea.Value{end+1} = 'Brak danych GPS do zapisania.'; % Informacja o braku danych
    end

    % Zapisz dane przyspieszenia w formacie CSV
    if ~isempty(app.ConvData.convCoordinates)
        cooFileName = fullfile(folderName, 'cooData.csv');
        writematrix(app.ConvData.convCoordinates, cooFileName); % Zapisz accData jako .csv
    else
        app.TextArea.Value{end+1} = 'Brak danych współrzędnych do zapisania.'; % Informacja o braku danych
    end

    % Eksportuj wykres przyspieszenia jako obraz
    if ~isempty(app.ConvData.convAcc)
        exportgraphics(app.UIAxes, fullfile(folderName, 'accPlot.png'));
    end

    % Eksportuj wykres GPS jako obraz
    if ~isempty(app.ConvData.convGPS)
        exportgraphics(app.UIAxes_2, fullfile(folderName, 'gpsPlot.png'));
    end

    if ~isempty(app.ConvData.convCoordinates)
        exportgraphics(app.Panel, fullfile(folderName, 'mapPlot.png'));
    end

    % Zwiększ licznik folderów dla następnego zapisu
    app.FolderCounter = app.FolderCounter + 1;

    app.TextArea.Value{end+1} = sprintf('Dane zapisane do folderu: %s.', folderName);


        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
    try
        writeline(app.SerialObj, 'AT+TEST=STOP'); % Wyślij komendę STOP
        pause(1);
        
        app.TextArea.Value{end+1} = 'Zatrzymane połączenie szeregowe.'; % Wyświetlenie komunikatu

        % Zamknięcie i odłączenie od portu
        if isvalid(app.SerialObj)
            clear app.SerialObj; % Wyczyszczenie obiektu, aby zamknąć połączenie
            app.SerialObj = []; % Wyzeruj zmienną obiektu
            disp('Połączenie zamknięte. Odłączono od portu szeregowego.'); % Komunikat potwierdzający zamknięcie
        end
    catch ME
        disp(['Błąd podczas wykonywania operacji: ', ME.message]); % Wyświetl komunikat o błędzie
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'AKCELEROMETR')
            xlabel(app.UIAxes, 'Czas [s]')
            ylabel(app.UIAxes, 'Przyśpieszenie [m/s^2]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'GPS')
            xlabel(app.UIAxes_2, 'Czas [s]')
            ylabel(app.UIAxes_2, 'Prędkość [km/h]')
            zlabel(app.UIAxes_2, 'Z')
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.RYSUJButton = uibutton(app.UIFigure, 'push');
            app.RYSUJButton.ButtonPushedFcn = createCallbackFcn(app, @RYSUJButtonPushed, true);
            app.RYSUJButton.Text = 'RYSUJ';
            app.ZAPISZButton = uibutton(app.UIFigure, 'push');
            app.ZAPISZButton.ButtonPushedFcn = createCallbackFcn(app, @ZAPISZButtonPushed, true);
            app.ZAPISZButton.Text = 'ZAPISZ';
            app.PORTDropDownLabel = uilabel(app.UIFigure);
            app.PORTDropDownLabel.Text = 'PORT';
            app.PORTDropDown = uidropdown(app.UIFigure);
            app.PORTDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5'};
            app.PORTDropDown.Editable = 'on';
            app.BAUDRATEDropDownLabel = uilabel(app.UIFigure);
            app.BAUDRATEDropDownLabel.Text = 'BAUDRATE';
            app.BAUDRATEDropDown = uidropdown(app.UIFigure);
            app.BAUDRATEDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5'};
            app.BAUDRATEDropDown.Editable = 'on';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aplikacja_Inzynierka

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end